classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LPCVOCODERLabel                matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        InputParametersPanel           matlab.ui.container.Panel
        ExcitationSignalDropDownLabel  matlab.ui.control.Label
        ExcitationSignalDropDown       matlab.ui.control.DropDown
        FrameLengthmsEditFieldLabel    matlab.ui.control.Label
        FrameLengthmsEditField         matlab.ui.control.NumericEditField
        WindowDropDownLabel            matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        OverlapEditFieldLabel          matlab.ui.control.Label
        OverlapEditField               matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        FsEditField                    matlab.ui.control.EditField
        LPCOrderEditFieldLabel         matlab.ui.control.Label
        LPCOrderEditField              matlab.ui.control.EditField
        RUNButton                      matlab.ui.control.Button
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes4_3                      matlab.ui.control.UIAxes
        UIAxes4_4                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            run("start_vocoder.m")
            plot(app.UIAxes4,zcrTX)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LPCVOCODERLabel = uilabel(app.UIFigure);
            app.LPCVOCODERLabel.Text = 'LPC VOCODER';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.ExcitationSignalDropDownLabel = uilabel(app.InputParametersPanel);
            app.ExcitationSignalDropDownLabel.Text = 'Excitation Signal';
            app.ExcitationSignalDropDown = uidropdown(app.InputParametersPanel);
            app.ExcitationSignalDropDown.Items = {'MIDI', 'Music File', 'Residuals'};
            app.ExcitationSignalDropDown.Value = 'MIDI';
            app.FrameLengthmsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FrameLengthmsEditFieldLabel.Text = 'Frame Length [ms]';
            app.FrameLengthmsEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.FrameLengthmsEditField.Limits = [5 50];
            app.FrameLengthmsEditField.Value = 20;
            app.WindowDropDownLabel = uilabel(app.InputParametersPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.InputParametersPanel);
            app.WindowDropDown.Items = {'Rectangular', 'Bartlett', 'Hanning', 'Hamming', 'Blackman-Harris', ''};
            app.WindowDropDown.Value = 'Rectangular';
            app.OverlapEditFieldLabel = uilabel(app.InputParametersPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap [%]';
            app.OverlapEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.OverlapEditField.Limits = [0 100];
            app.FsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FsEditFieldLabel.Enable = 'off';
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.InputParametersPanel, 'text');
            app.FsEditField.Editable = 'off';
            app.FsEditField.Enable = 'off';
            app.LPCOrderEditFieldLabel = uilabel(app.InputParametersPanel);
            app.LPCOrderEditFieldLabel.Enable = 'off';
            app.LPCOrderEditFieldLabel.Text = 'LPC Order';
            app.LPCOrderEditField = uieditfield(app.InputParametersPanel, 'text');
            app.LPCOrderEditField.Editable = 'off';
            app.LPCOrderEditField.Enable = 'off';
            app.RUNButton = uibutton(app.InputParametersPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.UIAxes4_2 = uiaxes(app.UIFigure);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            app.UIAxes4_3 = uiaxes(app.UIFigure);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            app.UIAxes4_4 = uiaxes(app.UIFigure);
            title(app.UIAxes4_4, 'Title')
            xlabel(app.UIAxes4_4, 'X')
            ylabel(app.UIAxes4_4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end